classdef MARC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MARC2020UIFigure        matlab.ui.Figure
        EventDropDownLabel      matlab.ui.control.Label
        EventDropDown           matlab.ui.control.DropDown
        SaveButton              matlab.ui.control.Button
        TogglePanel             matlab.ui.container.Panel
        OPRButton               matlab.ui.control.StateButton
        InitiationLineButton    matlab.ui.control.StateButton
        AutoPowerCellsButton    matlab.ui.control.StateButton
        TeleOpPowerCellsButton  matlab.ui.control.StateButton
        ControlPanelButton      matlab.ui.control.StateButton
        EndgameButton           matlab.ui.control.StateButton
        FoulsButton             matlab.ui.control.StateButton
        DisplayPanel            matlab.ui.container.Panel
        ResultsTable            matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        function displayTable = updateTable(app)
            % gather the data needed
            global teams
            oprValue = app.OPRButton.Value;
            initValue = app.InitiationLineButton.Value;
            autoPCValue = app.AutoPowerCellsButton.Value;
            teleopPCValue = app.TeleOpPowerCellsButton.Value;
            controlPanelValue = app.ControlPanelButton.Value;
            endgameValue = app.EndgameButton.Value;
            foulsValue = app.FoulsButton.Value;
            
            teamList = double.empty(0,1);
            rankList = double.empty(0,1);
            
            % create new columnName array and data table
            for i = 1:1:size(teams,1)
                teamList = [teamList; teams(i).number];
                rankList = [rankList; teams(i).rank];
            end
            newTable = table;
            newTable.Team = teamList;
            newTable.Rank = rankList;
            newColumnNames = {'Team','Rank'};
            
            % add the active stats
            if (oprValue)
                newColumnNames = [newColumnNames, {'OPR'}, {'DPR'}, {'CCWM'}];
                oprList = double.empty(0,1);
                dprList = double.empty(0,1);
                ccwmList = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    oprList = [oprList; teams(i).OPR];
                    dprList = [dprList; teams(i).DPR];
                    ccwmList = [ccwmList; teams(i).CCWM];
                end
                newTable.OPR = oprList;
                newTable.DPR = dprList;
                newTable.CCWM = ccwmList;
            end
            if (initValue)
                newColumnNames = [newColumnNames, {'Initiation Line %'}];
                initList = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    initList = [initList; teams(i).autoCross];
                end
                newTable.InitLine = initList;
            end
            if (autoPCValue)
                newColumnNames = [newColumnNames, {'Auto Cells - Lower'}, {'Auto Cells - Outer'}, {'Auto Cells - Inner'}, {'Auto Cell Points'}];
                autoLow = double.empty(0,1);
                autoOut = double.empty(0,1);
                autoIn = double.empty(0,1);
                autoPoints = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    autoLow = [autoLow; teams(i).autoCellsBottom];
                    autoOut = [autoOut; teams(i).autoCellsOuter];
                    autoIn = [autoIn; teams(i).autoCellsInner];
                    autoPoints = [autoPoints; teams(i).autoCellsBottom*2 + teams(i).autoCellsOuter*4 + teams(i).autoCellsInner*6];
                end
                newTable.autoLow = autoLow;
                newTable.autoOut = autoOut;
                newTable.autoIn = autoIn;
                newTable.autoPoints = autoPoints;
            end
            if (teleopPCValue)
                newColumnNames = [newColumnNames, {'TeleOp Cells - Lower'}, {'TeleOp Cells - Outer'}, {'TeleOp Cells - Inner'}, {'TeleOp Cell Points'}];
                teleopLow = double.empty(0,1);
                teleopOut = double.empty(0,1);
                teleopIn = double.empty(0,1);
                teleopPoints = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    teleopLow = [teleopLow; teams(i).teleOpCellsBottom];
                    teleopOut = [teleopOut; teams(i).teleOpCellsOuter];
                    teleopIn = [teleopIn; teams(i).teleOpCellsInner];
                    teleopPoints = [teleopPoints; teams(i).teleOpCellsBottom*1 + teams(i).teleOpCellsOuter*2 + teams(i).teleOpCellsInner*3];
                end
                newTable.teleopLow = teleopLow;
                newTable.teleopOut = teleopOut;
                newTable.teleopIn = teleopIn;
                newTable.teleopPoints = teleopPoints;
            end
            if (controlPanelValue)
                newColumnNames = [newColumnNames, {'Control Panel'}];
                cpList = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    cpList = [cpList; teams(i).controlPanel];
                end
                newTable.ControlPanel = cpList;
            end
            if (endgameValue)
                newColumnNames = [newColumnNames, {'Park %'}, {'Climb %'}];
                egParkList = double.empty(0,1);
                egClimbList = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    egParkList = [egParkList; teams(i).endgamePark];
                    egClimbList = [egClimbList; teams(i).endgameClimb];
                end
                newTable.egPark = egParkList;
                newTable.egClimb = egClimbList;
            end
            if (foulsValue)
                newColumnNames = [newColumnNames, {'Fouls'}];
                foulList = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    foulList = [foulList; teams(i).fouls];
                end
                newTable.Fouls = foulList;
            end
            
            % set columnName and return data
            app.ResultsTable.ColumnName = newColumnNames;
            displayTable = newTable;
        end
        
        function [OPRs, DPRs, CCWMs, InitLine, AutoPCLow, AutoPCOuter, AutoPCInner, TeleOpPCLow, TeleOpPCOuter, TeleOpPCInner, ControlPanel, EGPark, EGClimb, Fouls] = calculateStats(app)
            % sorry for the long declaration, there's just a lot of stats
            global teams
            global rawMatches
            
            % clean match list and filter out playoffs matches
            matchList = double.empty(0,19);
            for i = 1:1:size(rawMatches,1)
                % define each column, for sanity
                blueTeam1 = str2double(erase(convertCharsToStrings(rawMatches(i).alliances.blue.team_keys(1)), "frc"));
                blueTeam2 = str2double(erase(convertCharsToStrings(rawMatches(i).alliances.blue.team_keys(2)), "frc"));
                blueTeam3 = str2double(erase(convertCharsToStrings(rawMatches(i).alliances.blue.team_keys(3)), "frc"));
                % you ever wish there was just a "parse number" function?
                blueCross1 = strcmp(rawMatches(i).score_breakdown.blue.initLineRobot1,'Exited');
                blueCross2 = strcmp(rawMatches(i).score_breakdown.blue.initLineRobot2,'Exited');
                blueCross3 = strcmp(rawMatches(i).score_breakdown.blue.initLineRobot3,'Exited');
                blueAutoPCLow = rawMatches(i).score_breakdown.blue.autoCellsBottom; % note these six are not point values, they are number of goals
                blueAutoPCOut = rawMatches(i).score_breakdown.blue.autoCellsOuter;
                blueAutoPCIn = rawMatches(i).score_breakdown.blue.autoCellsInner;
                blueTeleOpPCLow = rawMatches(i).score_breakdown.blue.teleopCellsBottom;
                blueTeleOpPCOut = rawMatches(i).score_breakdown.blue.teleopCellsOuter;
                blueTeleOpPCIn = rawMatches(i).score_breakdown.blue.teleopCellsInner;
                blueControlPanel = rawMatches(i).score_breakdown.blue.controlPanelPoints; % this one is a point value
                % endgame is complicated bc there are 3 possible states
                blueEnd1 = strcmp(rawMatches(i).score_breakdown.blue.endgameRobot1,'Park');
                blueEnd2 = strcmp(rawMatches(i).score_breakdown.blue.endgameRobot2,'Park');
                blueEnd3 = strcmp(rawMatches(i).score_breakdown.blue.endgameRobot3,'Park');
                if (strcmp(rawMatches(i).score_breakdown.blue.endgameRobot1,'Hang'))
                    blueEnd1 = 2;
                end
                if (strcmp(rawMatches(i).score_breakdown.blue.endgameRobot2,'Hang'))
                    blueEnd2 = 2;
                end
                if (strcmp(rawMatches(i).score_breakdown.blue.endgameRobot3,'Hang'))
                    blueEnd3 = 2;
                end
                blueFouls = rawMatches(i).score_breakdown.blue.foulPoints; % couldn't forget fouls!
                
                % do it again for the red alliance!
                redTeam1 = str2double(erase(convertCharsToStrings(rawMatches(i).alliances.red.team_keys(1)), "frc"));
                redTeam2 = str2double(erase(convertCharsToStrings(rawMatches(i).alliances.red.team_keys(2)), "frc"));
                redTeam3 = str2double(erase(convertCharsToStrings(rawMatches(i).alliances.red.team_keys(3)), "frc"));
                redCross1 = strcmp(rawMatches(i).score_breakdown.red.initLineRobot1,'Exited');
                redCross2 = strcmp(rawMatches(i).score_breakdown.red.initLineRobot2,'Exited');
                redCross3 = strcmp(rawMatches(i).score_breakdown.red.initLineRobot3,'Exited');
                redAutoPCLow = rawMatches(i).score_breakdown.red.autoCellsBottom; % note these six are not point values, they are number of goals
                redAutoPCOut = rawMatches(i).score_breakdown.red.autoCellsOuter;
                redAutoPCIn = rawMatches(i).score_breakdown.red.autoCellsInner;
                redTeleOpPCLow = rawMatches(i).score_breakdown.red.teleopCellsBottom;
                redTeleOpPCOut = rawMatches(i).score_breakdown.red.teleopCellsOuter;
                redTeleOpPCIn = rawMatches(i).score_breakdown.red.teleopCellsInner;
                redControlPanel = rawMatches(i).score_breakdown.red.controlPanelPoints; % this one is a point value
                % endgame is complicated bc there are 3 possible states
                redEnd1 = strcmp(rawMatches(i).score_breakdown.red.endgameRobot1,'Park');
                redEnd2 = strcmp(rawMatches(i).score_breakdown.red.endgameRobot2,'Park');
                redEnd3 = strcmp(rawMatches(i).score_breakdown.red.endgameRobot3,'Park');
                if (strcmp(rawMatches(i).score_breakdown.red.endgameRobot1,'Hang'))
                    redEnd1 = 2;
                end
                if (strcmp(rawMatches(i).score_breakdown.red.endgameRobot2,'Hang'))
                    redEnd2 = 2;
                end
                if (strcmp(rawMatches(i).score_breakdown.red.endgameRobot3,'Hang'))
                    redEnd3 = 2;
                end
                redFouls = rawMatches(i).score_breakdown.red.foulPoints; % couldn't forget fouls!
                
                % these two are used twice for OPR and DPR
                blueAllianceScore = rawMatches(i).alliances.blue.score;
                redAllianceScore = rawMatches(i).alliances.red.score;
                
                if (strcmp(rawMatches(i).comp_level,'qm')) % make sure this is a qual match
                    matchList = [matchList; blueTeam1, blueTeam2, blueTeam3, blueAllianceScore, redAllianceScore, blueCross1, blueCross2, blueCross3, blueAutoPCLow, blueAutoPCOut, blueAutoPCIn, blueTeleOpPCLow, blueTeleOpPCOut, blueTeleOpPCIn, blueControlPanel, blueEnd1, blueEnd2, blueEnd3, blueFouls];
                    matchList = [matchList; redTeam1, redTeam2, redTeam3, redAllianceScore, blueAllianceScore, redCross1, redCross2, redCross3, redAutoPCLow, redAutoPCOut, redAutoPCIn, redTeleOpPCLow, redTeleOpPCOut, redTeleOpPCIn, redControlPanel, redEnd1, redEnd2, redEnd3, redFouls];
                end
            end
            
            % create teams logical array
            matchTeams = double.empty(0,size(teams,1));
            for i = 1:1:size(matchList,1)
                singleAlliance = double.empty(1,0);
                for j = 1:1:size(teams,1)
                    if (matchList(i,1) == teams(j).number || matchList(i,2) == teams(j).number || matchList(i,3) == teams(j).number) % check if team j is one of the teams in match i
                        singleAlliance = [singleAlliance, 1];
                    else 
                        singleAlliance = [singleAlliance, 0];
                    end
                end
                matchTeams = [matchTeams; singleAlliance];
            end
            
            % run calculations with each column of scores array
            OPRs = matchTeams\matchList(:,4);
            DPRs = matchTeams\matchList(:,5);
            CCWMs = OPRs-DPRs; % it's called Calculated Contribution to Winning Margin so it is equal to points you earn minus points you allow the other side to earn
            AutoPCLow = matchTeams\matchList(:,9);
            AutoPCOuter = matchTeams\matchList(:,10);
            AutoPCInner = matchTeams\matchList(:,11);
            TeleOpPCLow = matchTeams\matchList(:,12);
            TeleOpPCOuter = matchTeams\matchList(:,13);
            TeleOpPCInner = matchTeams\matchList(:,14);
            ControlPanel = matchTeams\matchList(:,15);
            Fouls = matchTeams\matchList(:,19);
            
            % now do ones that are successes
            successList = double.empty(0,3);
            for i = 1:1:size(teams,1)
                autoCrossCount = 0;
                egParkCount = 0;
                egClimbCount = 0;
                matchCount = 0;
                for j = 1:1:size(matchList,1)
                    if (matchList(j,1) == teams(i).number) % if team is blue/red 1
                        matchCount = matchCount + 1;
                        if (matchList(j,6) == 1) % in many languages this if statement could be 1 line instead of 3
                            autoCrossCount = autoCrossCount + 1;
                        end
                        if (matchList(j,16) == 1)
                            egParkCount = egParkCount + 1;
                        end
                        if (matchList(j,16) == 2)
                            egClimbCount = egClimbCount + 1;
                        end
                    end
                    
                    if (matchList(j,2) == teams(i).number) % if team is blue/red 2
                        matchCount = matchCount + 1;
                        if (matchList(j,7) == 1) % in many languages this if statement could be 1 line instead of 3
                            autoCrossCount = autoCrossCount + 1;
                        end
                        if (matchList(j,17) == 1)
                            egParkCount = egParkCount + 1;
                        end
                        if (matchList(j,17) == 2)
                            egClimbCount = egClimbCount + 1;
                        end
                    end
                    
                    if (matchList(j,3) == teams(i).number) % if team is blue/red 3
                        matchCount = matchCount + 1;
                        if (matchList(j,8) == 1) % in many languages this if statement could be 1 line instead of 3
                            autoCrossCount = autoCrossCount + 1;
                        end
                        if (matchList(j,18) == 1)
                            egParkCount = egParkCount + 1;
                        end
                        if (matchList(j,18) == 2)
                            egClimbCount = egClimbCount + 1;
                        end
                    end
                end
                if (matchCount == 0) % do not divide by 0
                    matchCount = 1;
                end
                successList = [successList; autoCrossCount*100/matchCount, egParkCount*100/matchCount, egClimbCount*100/matchCount];
            end
            
            % return success stats
            InitLine = successList(:,1);
            EGPark = successList(:,2);
            EGClimb = successList(:,3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all'); % they just annoy me
            
            % get the events list & populate dropdown
            global key
            key = 'IR6AEJnuVkwxlWfruGbLYvLFTqD4sSqQTlXrLCSb5XbGGseWCSHQQnid64VyE8m9'; % this a Blue Alliance API key!
            eventsUrl = 'https://www.thebluealliance.com/api/v3/events/2020';
            eventsList = webread(eventsUrl,'X-TBA-Auth-Key',key);
            global eventNames
            global eventKeys
            eventNames = string.empty(0,1);
            eventKeys = string.empty(0,1);
            eventNames = [eventNames; 'Pick an Event']; % make sure the calculation doesn't run if the user selects this for some reason
            for i = 1:1:size(eventsList,1)
                eventNames = [eventNames; eventsList(i).short_name, ' (', upper(eventsList(i).event_code),')'];
                eventKeys = [eventKeys; eventsList(i).key];
            end
            
            app.EventDropDown.Items = eventNames;
        end

        % Value changed function: EventDropDown
        function EventDropDownValueChanged(app, event)
            value = app.EventDropDown.Value;
            global key
            global teams % look it's the first instance of the teams struct array!!
            global rawMatches % need this to be global for the calculation method
            global eventNames
            global eventKeys
            global eventCode
            
            % make sure the new value isn't the placeholder
            if (~strcmp(value,'Pick an Event'))
                
                % figure out the event key
                for i = 1:1:size(eventNames)
                    if (strcmp(value,eventNames(i)))
                        eventCode = eventKeys(i-1); % -1 bc I added a placeholder at the top of the names list
                    end
                end
                
                % get team list, team ranks, & match data
                teamsUrl = ['https://www.thebluealliance.com/api/v3/event/', convertStringsToChars(eventCode), '/teams/simple'];
                matchesUrl = ['https://www.thebluealliance.com/api/v3/event/', convertStringsToChars(eventCode), '/matches'];
                rankUrl = ['https://www.thebluealliance.com/api/v3/event/',convertStringsToChars(eventCode),'/rankings'];
                rawTeamList = webread(teamsUrl, 'X-TBA-Auth-Key', key);
                rawMatches = webread(matchesUrl, 'X-TBA-Auth-Key', key);
                rawRankings = webread(rankUrl,'X-TBA-Auth-Key',key);
                
                % add teams and ranks to teams array
                teams = struct(team);
                teams(1).number = rawTeamList(1).team_number;
                teams(1).name = convertCharsToStrings(rawTeamList(1).nickname);
                for i = 2:1:size(rawTeamList,1)
                    teams = [teams; struct(team)];
                    teams(i).number = rawTeamList(i).team_number;
                    teams(i).name = convertCharsToStrings(rawTeamList(i).nickname);
                end
                temp = struct2table(teams);
                temp = sortrows(temp,'number');
                teams = table2struct(temp);
                for i = 1:1:size(rawTeamList)
                    for j = 1:1:size(rawRankings.rankings,1) % get team's rank
                        rankedTeam = str2num(erase(convertCharsToStrings(rawRankings.rankings(j).team_key),"frc")); % why isn't it just a number?
                        if (teams(i).number == rankedTeam)
                            teams(i).rank = rawRankings.rankings(j).rank;
                        end
                    end
                    if (isempty(teams(i).rank))
                        teams(i).rank = 99;
                    end
                end
                
                
                % check that the event actually happened (thanks 2020)
                if (size(rawMatches,1) ~= 0)
                    
                    % run stat calculations
                    [OPRs, DPRs, CCWMs, InitLine, AutoPCLow, AutoPCOuter, AutoPCInner, TeleOpPCLow, TeleOpPCOuter, TeleOpPCInner, ControlPanel, EGPark, EGClimb, Fouls] = calculateStats(app);
                    for i = 1:1:size(teams,1) % add the calculated stats to the teams array
                        teams(i).OPR = OPRs(i);
                        teams(i).DPR = DPRs(i);
                        teams(i).CCWM = CCWMs(i);
                        teams(i).autoCross = InitLine(i);
                        teams(i).autoCellsBottom = AutoPCLow(i);
                        teams(i).autoCellsOuter = AutoPCOuter(i);
                        teams(i).autoCellsInner = AutoPCInner(i);
                        teams(i).teleOpCellsBottom = TeleOpPCLow(i);
                        teams(i).teleOpCellsOuter = TeleOpPCOuter(i);
                        teams(i).teleOpCellsInner = TeleOpPCInner(i);
                        teams(i).controlPanel = ControlPanel(i);
                        teams(i).endgamePark = EGPark(i);
                        teams(i).endgameClimb = EGClimb(i);
                        teams(i).fouls = Fouls(i);
                    end
                    
                    % update table
                    app.ResultsTable.Data = updateTable(app);
                    
                    % enable toggle buttons and save button
                    app.OPRButton.Enable = true;
                    app.InitiationLineButton.Enable = true;
                    app.AutoPowerCellsButton.Enable = true;
                    app.TeleOpPowerCellsButton.Enable = true;
                    app.ControlPanelButton.Enable = true;
                    app.EndgameButton.Enable = true;
                    app.FoulsButton.Enable = true;
                    app.SaveButton.Enable = true;
                    
                else
                    fig = app.MARC2020UIFigure;
                    uialert(fig,'This event either was canceled or hasn''t started yet','No Matches');
                end
            end
        end

        % Value changed function: OPRButton
        function OPRButtonValueChanged(app, event)
            value = app.OPRButton.Value;
            
            if (value) % change color appropriately
                app.OPRButton.BackgroundColor = [0, 0.54, 0];
            else
                app.OPRButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Value changed function: InitiationLineButton
        function InitiationLineButtonValueChanged(app, event)
            value = app.InitiationLineButton.Value;
            
            if (value) % change color appropriately
                app.InitiationLineButton.BackgroundColor = [0, 0.54, 0];
            else
                app.InitiationLineButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Value changed function: AutoPowerCellsButton
        function AutoPowerCellsButtonValueChanged(app, event)
            value = app.AutoPowerCellsButton.Value;
            
            if (value) % change color appropriately
                app.AutoPowerCellsButton.BackgroundColor = [0, 0.54, 0];
            else
                app.AutoPowerCellsButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Value changed function: TeleOpPowerCellsButton
        function TeleOpPowerCellsButtonValueChanged(app, event)
            value = app.TeleOpPowerCellsButton.Value;
            
            if (value) % change color appropriately
                app.TeleOpPowerCellsButton.BackgroundColor = [0, 0.54, 0];
            else
                app.TeleOpPowerCellsButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Value changed function: ControlPanelButton
        function ControlPanelButtonValueChanged(app, event)
            value = app.ControlPanelButton.Value;
            
            if (value) % change color appropriately
                app.ControlPanelButton.BackgroundColor = [0, 0.54, 0];
            else
                app.ControlPanelButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Value changed function: EndgameButton
        function EndgameButtonValueChanged(app, event)
            value = app.EndgameButton.Value;
            
            if (value) % change color appropriately
                app.EndgameButton.BackgroundColor = [0, 0.54, 0];
            else
                app.EndgameButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Value changed function: FoulsButton
        function FoulsButtonValueChanged(app, event)
            value = app.FoulsButton.Value;
            
            if (value) % change color appropriately
                app.FoulsButton.BackgroundColor = [0, 0.54, 0];
            else
                app.FoulsButton.BackgroundColor = [0.40, 0.40, 0.40];
            end
            
            % update table
            app.ResultsTable.Data = updateTable(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save teams struct to excel file
            global teams
            global eventCode
            
            fileName = ['Team Stats - ', convertStringsToChars(eventCode), '.xlsx'];
            title = ['Save ', convertStringsToChars(app.EventDropDown.Value)];
            [file, path] = uiputfile(fileName, title);
            
            if (file ~= 0) % only save if there is a file path
                % gotta create this table manually bc it comes out weird if I use struct2table
                saveTable = table;
                teamsList = double.empty(0,16);
                teamNamesList = string.empty(0,1);
                rankList = double.empty(0,1);
                oprList = double.empty(0,1);
                dprList = double.empty(0,1);
                ccwmList = double.empty(0,1);
                crossList = double.empty(0,1);
                autoCellsBList = double.empty(0,1);
                autoCellsOList = double.empty(0,1);
                autoCellsIList = double.empty(0,1);
                teleopCellsBList = double.empty(0,1);
                teleopCellsOList = double.empty(0,1);
                teleopCellsIList = double.empty(0,1);
                controlPanelList = double.empty(0,1);
                parkList = double.empty(0,1);
                climbList = double.empty(0,1);
                foulList = double.empty(0,1);
                for i = 1:1:size(teams,1)
                    teamsList = [teamsList; teams(i).number];
                    teamNamesList = [teamNamesList; teams(i).name];
                    rankList = [rankList; teams(i).rank];
                    oprList = [oprList; teams(i).OPR];
                    dprList = [dprList; teams(i).DPR];
                    ccwmList = [ccwmList; teams(i).CCWM];
                    crossList = [crossList; teams(i).autoCross];
                    autoCellsBList = [autoCellsBList; teams(i).autoCellsBottom];
                    autoCellsOList = [autoCellsOList; teams(i).autoCellsOuter];
                    autoCellsIList = [autoCellsIList; teams(i).autoCellsInner];
                    teleopCellsBList = [teleopCellsBList; teams(i).teleOpCellsBottom];
                    teleopCellsOList = [teleopCellsOList; teams(i).teleOpCellsOuter];
                    teleopCellsIList = [teleopCellsIList; teams(i).teleOpCellsInner];
                    controlPanelList = [controlPanelList; teams(i).controlPanel];
                    parkList = [parkList; teams(i).endgamePark];
                    climbList = [climbList; teams(i).endgameClimb];
                    foulList = [foulList; teams(i).fouls];
                end
                saveTable.Number = teamsList;
                saveTable.Name = teamNamesList;
                saveTable.Ranking = rankList;
                saveTable.OPR = oprList;
                saveTable.DPR = dprList;
                saveTable.CCWM = ccwmList;
                saveTable.InitLineCross = crossList;
                saveTable.AutoCellsLower = autoCellsBList;
                saveTable.AutoCellsOuter = autoCellsOList;
                saveTable.AutoCellsInner = autoCellsIList;
                saveTable.AutoCellPoints = autoCellsBList*2 + autoCellsOList*4 + autoCellsIList*6;
                saveTable.TeleOpCellsLower = teleopCellsBList;
                saveTable.TeleOpCellsOuter = teleopCellsOList;
                saveTable.TeleOpCellsInner = teleopCellsIList;
                saveTable.TeleOpCellPoints = teleopCellsBList*1 + teleopCellsOList*2 + teleopCellsIList*3;
                saveTable.ControlPanel = controlPanelList;
                saveTable.EndgamePark = parkList;
                saveTable.EndgameClimb = climbList;
                saveTable.Fouls = foulList;
                
                writetable(saveTable,[path,file]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MARC2020UIFigure = uifigure('Visible', 'off');
            app.MARC2020UIFigure.Name = 'Multi-Attribute Rating Calculator (2020 Infinite Recharge)';
            app.EventDropDownLabel = uilabel(app.MARC2020UIFigure);
            app.EventDropDownLabel.Text = 'Event';
            app.EventDropDown = uidropdown(app.MARC2020UIFigure);
            app.EventDropDown.Items = {'Pick an Event'};
            app.EventDropDown.Editable = 'on';
            app.EventDropDown.ValueChangedFcn = createCallbackFcn(app, @EventDropDownValueChanged, true);
            app.EventDropDown.Value = 'Pick an Event';
            app.SaveButton = uibutton(app.MARC2020UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save Event Stats to Excel';
            app.TogglePanel = uipanel(app.MARC2020UIFigure);
            app.OPRButton = uibutton(app.TogglePanel, 'state');
            app.OPRButton.ValueChangedFcn = createCallbackFcn(app, @OPRButtonValueChanged, true);
            app.OPRButton.Enable = 'off';
            app.OPRButton.Text = 'OPR';
            app.OPRButton.Value = true;
            app.InitiationLineButton = uibutton(app.TogglePanel, 'state');
            app.InitiationLineButton.ValueChangedFcn = createCallbackFcn(app, @InitiationLineButtonValueChanged, true);
            app.InitiationLineButton.Enable = 'off';
            app.InitiationLineButton.Text = 'Initiation Line';
            app.AutoPowerCellsButton = uibutton(app.TogglePanel, 'state');
            app.AutoPowerCellsButton.ValueChangedFcn = createCallbackFcn(app, @AutoPowerCellsButtonValueChanged, true);
            app.AutoPowerCellsButton.Enable = 'off';
            app.AutoPowerCellsButton.Text = 'Auto Power Cells';
            app.TeleOpPowerCellsButton = uibutton(app.TogglePanel, 'state');
            app.TeleOpPowerCellsButton.ValueChangedFcn = createCallbackFcn(app, @TeleOpPowerCellsButtonValueChanged, true);
            app.TeleOpPowerCellsButton.Enable = 'off';
            app.TeleOpPowerCellsButton.Text = 'TeleOp Power Cells';
            app.ControlPanelButton = uibutton(app.TogglePanel, 'state');
            app.ControlPanelButton.ValueChangedFcn = createCallbackFcn(app, @ControlPanelButtonValueChanged, true);
            app.ControlPanelButton.Enable = 'off';
            app.ControlPanelButton.Text = 'Control Panel';
            app.EndgameButton = uibutton(app.TogglePanel, 'state');
            app.EndgameButton.ValueChangedFcn = createCallbackFcn(app, @EndgameButtonValueChanged, true);
            app.EndgameButton.Enable = 'off';
            app.EndgameButton.Text = 'Endgame';
            app.FoulsButton = uibutton(app.TogglePanel, 'state');
            app.FoulsButton.ValueChangedFcn = createCallbackFcn(app, @FoulsButtonValueChanged, true);
            app.FoulsButton.Enable = 'off';
            app.FoulsButton.Text = 'Fouls';
            app.DisplayPanel = uipanel(app.MARC2020UIFigure);
            app.ResultsTable = uitable(app.DisplayPanel);
            app.ResultsTable.ColumnName = {'Team'; 'Rank'; 'OPR'; 'DPR'; 'CCWM'};
            app.ResultsTable.RowName = {};
            app.ResultsTable.ColumnSortable = true;
            % Show the figure after all components are created
            app.MARC2020UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MARC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MARC2020UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MARC2020UIFigure)
        end
    end
end